classdef cvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        SettingsMenu                matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        MainContentPanel            matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        VisualizationStyleDropDown  matlab.ui.control.DropDown
        VisualizationStyleDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        ContentPanel1 matlab.ui.container.Panel
        ContentPanel2 matlab.ui.container.Panel
        ContentPanel3 matlab.ui.container.Panel
        
        P1Grid          matlab.ui.container.GridLayout
        P1Axes          matlab.ui.control.UIAxes
        P1SPanel        matlab.ui.container.Panel
        P1SliderGrid    matlab.ui.container.GridLayout
        P1SliderLabel   matlab.ui.control.Label
        P1Slider        matlab.ui.control.Slider

        P2Grid          matlab.ui.container.GridLayout
        P2UIAxes        matlab.ui.control.UIAxes
        

        ImageFiles  % Cell array of full image file paths
        ImageDates  % datetime array parsed from filenames
        Images      % Cell array to store loaded images (optional, for faster access)
        HelpHTML
    end
    
    methods (Access = private)

        function sliderValueChanged(app, src, event)
            idx = round(src.Value);
            idx = max(1, min(idx, length(app.Images)));
            src.Value = idx;
        
            % Display image using uiimage
            imshow(app.Images{idx}, 'Parent', app.P1Axes);
        end
        
        function loadImagesFromFolder(app)
            % Ask user to select a folder
            folderPath = uigetdir(pwd, 'Select Folder Containing Images');
            if folderPath == 0
                % User canceled
                return
            end
            
            % Get list of JPG files matching pattern YYYY_MM.jpg
            files = dir(fullfile(folderPath, '*.jpg'));
            
            % Filter files matching pattern YYYY_MM.jpg
            % Regular expression for filename like "2023_04.jpg"
            %pattern = '^\d{4}_\d{2}\.jpg$';
            
            validFiles = {};
            validDates = datetime.empty(1,0);
        
            for k = 1:length(files)
                fname = files(k).name;
                
                dt = app.parseDateFromFilename(fname);
                validFiles{end+1} = fullfile(folderPath, fname);
                validDates(end+1) = dt;

            end
            
            if isempty(validFiles)
                uialert(app.UIFigure, 'No valid images found with pattern YYYY_MM.jpg', 'No Images');
                return
            end
            
            % Sort images by date
            [validDates, sortIdx] = sort(validDates);
            validFiles = validFiles(sortIdx);
            
            % Optionally, load images now (or load on demand)
            imgs = cell(1, length(validFiles));
            for k = 1:length(validFiles)
                imgs{k} = imread(validFiles{k});
            end
            
            % Store in app properties
            app.ImageFiles = validFiles;
            app.ImageDates = validDates;
            app.Images = imgs;
            
            % update panel 1
            imshow(app.Images{1}, 'Parent', app.P1Axes);

            app.P1Slider.Limits = [1 length(app.Images)];
            app.P1Slider.MajorTicks = 1:length(app.Images);
            app.P1Slider.MajorTickLabels = cellstr(datestr(app.ImageDates, 'yyyy_mm'));
            app.P1Slider.Value = 1;

            % update panel 2
            app.displayImagesInSecondPanel()            
        end
        
        function date = parseDateFromFilename(app, filename)
            %PARSEDATEFROMFILENAME Extrahiert Jahr und Monat aus einem Dateinamen.
            %   Unterstützt Formate wie '2024_06.jpg' und '06_2024.jpg'.
            %   Gibt ein datetime-Objekt mit dem 1. des Monats zurück.
            %
            %   Beispiel:
            %       dt = parseDateFromFilename('2024_06.jpg')
            %       dt = parseDateFromFilename('06_2024.jpg')
        
            [~, name, ~] = fileparts(filename);  % Entfernt .jpg
            tokens = regexp(name, '^(\d{4})_(\d{1,2})$', 'tokens');
            if isempty(tokens)
                tokens = regexp(name, '^(\d{1,2})_(\d{4})$', 'tokens');
            end
            if isempty(tokens)
                error('Filename does not match expected format');
            end
            tokens = tokens{1};
            
            if str2double(tokens{1}) > 1900  % Fall: YYYY_MM
                year = str2double(tokens{1});
                month = str2double(tokens{2});
            else  % Fall: MM_YYYY
                month = str2double(tokens{1});
                year = str2double(tokens{2});
            end
            
            date = datetime(year, month, 1);
        end

        function displayImagesInSecondPanel(app)
            % Clear existing content from panel2
            delete(app.P2Grid.Children);
        
            numImages = numel(app.Images);
        
            % Determine grid size for layout
            numCols = ceil(sqrt(numImages));
            numRows = ceil(numImages / numCols);
        
            % Create grid layout in Panel2
            app.P2Grid = uigridlayout(app.MainContentPanel, [numRows, numCols]);
            app.P2Grid.Visible = 'off';
            app.P2Grid.RowSpacing = 5;
            app.P2Grid.ColumnSpacing = 5;
            app.P2Grid.Padding = [10 10 10 10];
        
            % Display each image in its own uiaxes
            for i = 1:numImages
                ax = uiaxes(app.P2Grid);
                imshow(app.Images{i}, 'Parent', ax);
                ax.XTick = [];
                ax.YTick = [];
                title(ax, sprintf('Image %d', i));
            end
        end

        function closeHelpHTML(app, src, event)
            disp('Event empfangen:');
            disp(event.Data);
            if isfield(event.Data, 'type') && strcmp(event.Data.type, 'closeHelp')
                delete(app.HelpHTML);
                app.HelpHTML = [];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Dimensions: same as MainContentPanel
            parent = app.MainContentPanel;
        
            % Panel 1
            %app.ContentPanel1 = uipanel(parent, ...
            %    'Units', 'normalized', ...
            %    'Position', [0 0 620 400], ...
            %    'BackgroundColor', 'white', ...
            %    'Visible', 'on');

            % Create Panel 1 Grid layout
            app.P1Grid = uigridlayout(app.MainContentPanel);
            app.P1Grid.ColumnWidth = {'1x'};
            app.P1Grid.RowHeight = {'1x', 60};
            app.P1Grid.Visible = 'on';

            % Create Image
            app.P1Axes = uiaxes(app.P1Grid);
            app.P1Axes.Layout.Row = 1;
            app.P1Axes.Layout.Column = 1;
            app.P1Axes.XTick = [];
            app.P1Axes.YTick = [];

            % Create Slider Grid
            app.P1SliderGrid = uigridlayout(app.P1Grid);
            app.P1SliderGrid.ColumnWidth = {60, '1x'};
            app.P1SliderGrid.RowHeight = {'1x'};
            app.P1SliderGrid.Layout.Row = 2;
            app.P1SliderGrid.Layout.Column = 1;

            % Create SliderLabel
            app.P1SliderLabel = uilabel(app.P1SliderGrid);
            app.P1SliderLabel.HorizontalAlignment = 'right';
            app.P1SliderLabel.Layout.Row = 1;
            app.P1SliderLabel.Layout.Column = 1;
            app.P1SliderLabel.Text = 'Slider';

            % Create Slider
            app.P1Slider = uislider(app.P1SliderGrid);
            app.P1Slider.Layout.Row = 1;
            app.P1Slider.Layout.Column = 2;

            app.P1Slider.ValueChangedFcn = @(src,event) sliderValueChanged(app, src, event);
                    
            % Panel 2

            % Create P2 Grid Layout
            app.P2Grid = uigridlayout(app.MainContentPanel);
            app.P2Grid.ColumnWidth = {'1x'};
            app.P2Grid.RowHeight = {'1x'};
            app.P2Grid.Visible = 'off';
        
            % Panel 3
            app.ContentPanel3 = uipanel(parent, ...
                'Units', 'normalized', ...
                'Position', [0 0 620 400], ...
                'BackgroundColor', 'white', ...
                'Visible', 'off');
        
            % Add some sample content
            uilabel(app.ContentPanel3, 'Text', 'Panel 3', 'Position', [10 10 100 22]);
        end

        % Value changed function: VisualizationStyleDropDown
        function VisualizationStyleDropDownValueChanged(app, event)
            val = app.VisualizationStyleDropDown.Value;
        
            % Hide all
            app.P1Grid.Visible = 'off';
            app.P2Grid.Visible = 'off';
            % app.ContentPanel3.Visible = 'off';
            % app.ContentPanel4.Visible = 'off';
        
            % Show selected
            switch val
                case 'Option 1'
                    app.P1Grid.Visible = 'on';
                case 'Option 2'
                    app.P2Grid.Visible = 'on';
                case 'Option 3'
                    % app.ContentPanel3.Visible = 'on';
                case 'Option 4'
                    % app.ContentPanel4.Visible = 'on';
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            loadImagesFromFolder(app);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app.UIFigure)
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.tif'}, 'Bild speichern als');

            if ischar(file)
                exportgraphics(app.UIAxes, fullfile(path, file));
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            if ~isempty(app.HelpHTML) && isvalid(app.HelpHTML)
                delete(app.HelpHTML);
                app.HelpHTML = [];
                return;
            end
        
            helpFile = fullfile(pwd, 'help.html');
            if ~isfile(helpFile)
                uialert(app.UIFigure, 'Hilfedatei nicht gefunden.', 'Fehler');
                return;
            end
        
            app.HelpHTML = uihtml(app.UIFigure, ...
                'HTMLSource', helpFile, ...
                'Position', [100 100 600 400]);
        
            % Reagiere auf JavaScript-Nachricht
            app.HelpHTML.DataChangedFcn = @(src, event) closeHelpHTML(app, src, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.HelpMenu = uimenu(app.SettingsMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.VisualizationStyleDropDownLabel = uilabel(app.GridLayout2);
            app.VisualizationStyleDropDownLabel.Text = 'Visualization Style';
            app.VisualizationStyleDropDown = uidropdown(app.GridLayout2);
            app.VisualizationStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationStyleDropDownValueChanged, true);
            app.MainContentPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end